classdef imageInpainting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        showButton                matlab.ui.control.Button
        saveButton                matlab.ui.control.Button
        ordertypeButtonGroup      matlab.ui.container.ButtonGroup
        gradientButton            matlab.ui.control.RadioButton
        tensorButton              matlab.ui.control.RadioButton
        inputImageButton          matlab.ui.control.Button
        imageinpaintingdemoLabel  matlab.ui.control.Label
        patchsizeEditFieldLabel   matlab.ui.control.Label
        patchsizeEditField        matlab.ui.control.EditField
        dateLabel                 matlab.ui.control.Label
        dateDatePicker            matlab.ui.control.DatePicker
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ori % 原图像
        mask % 对应的mask
        order
        patchSize
        
        newImage 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: inputImageButton
        function inputImage(app, event)
            [filename,pathname]=uigetfile({'*.jpg';'*.bmp''*.gif'},'choose your image');
            if pathname==0
                return;
            end
            str=[pathname filename];
            [root,~,~] = fileparts(fileparts(pathname));
            maskStr = fullfile(root,'mask',filename);
            if ~isfile(maskStr)
                errordlg('Please use "createImgMask.m" to interactively create your mask image!')
                return;
            end
            
            app.ori=imread(str);
            if numel(size(app.ori))==3
                app.ori = rgb2gray(app.ori);
            end
            app.mask = logical(imread(maskStr));
            if numel(size(app.mask))==3
                app.mask = app.mask(:,:,1)|app.mask(:,:,2)|app.mask(:,:,3);
            end
            
            ax = app.UIAxes;
            imshow(app.ori,'Parent',ax);
        end

        % Button pushed function: showButton
        function oriShow(app, event)
            if isempty(app.ori)
                msgbox('Please import images first!');
                return;
            end
            ax = app.UIAxes;
            imshow(app.ori,'Parent',ax);
        end

        % Selection changed function: ordertypeButtonGroup
        function orderType(app, event)
            selectedButton = app.ordertypeButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton,'gradient')
                app.order = 'gradient';
            else
                app.order = 'tensor';
            end
            value = app.patchsizeEditField.Value;
            app.patchSize = str2double(value);
            if app.patchSize<3|| ~isnumeric(app.patchSize)
                errordlg('Please enter an integer greater than 2 and less than the image size!')
                return;
            end
            if isempty(app.ori) || isempty(app.mask)
                errordlg('Please input an image and ensure that there is a corresponding mask image in the mask folder!')
                return;
            end
            app.newImage = inpaintExemplar(app.ori,app.mask,'PatchSize',app.patchSize,'FillOrder',app.order);
            % Update input image with output.
            ax = app.UIAxes2;
            imshow(app.newImage,'Parent',ax);
        end

        % Value changed function: patchsizeEditField
        function patch(app, event)
            value = app.patchsizeEditField.Value;
            app.patchSize = str2double(value);
            if app.patchSize<3|| ~isnumeric(app.patchSize)
                errordlg('Please enter an integer greater than 2 and less than the image size!')
                return;
            end
            if isempty(app.ori) || isempty(app.mask)
                errordlg('Please input an image and ensure that there is a corresponding mask image in the mask folder!')
                return;
            end
            app.order = app.ordertypeButtonGroup.SelectedObject.Text;
            app.newImage = inpaintExemplar(app.ori,app.mask,'PatchSize',app.patchSize,'FillOrder',app.order);
            % Update input image with output.
            ax = app.UIAxes2;
            imshow(app.newImage,'Parent',ax);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            
            if isempty(app.newImage)
                msgbox('Please set relevant parameters! And then save the image!')
                return;
            else
                time = datestr(now,'dd_mmm_yyyy_HH_MM_SS');
                savePath = ['save/',time,'_inpaint.jpg'];
                if ~isfolder('save/')
                    mkdir('save/');
                end
                imwrite(app.newImage,savePath);
                msgbox("The image has been saved to:"+fullfile(pwd,savePath));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '图像修复';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'origin')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'result')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.showButton = uibutton(app.UIFigure, 'push');
            app.showButton.ButtonPushedFcn = createCallbackFcn(app, @oriShow, true);
            app.showButton.Text = 'show';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.ordertypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ordertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @orderType, true);
            app.ordertypeButtonGroup.Title = 'order type';
            app.gradientButton = uiradiobutton(app.ordertypeButtonGroup);
            app.gradientButton.Text = 'gradient';
            app.tensorButton = uiradiobutton(app.ordertypeButtonGroup);
            app.tensorButton.Text = 'tensor';
            app.tensorButton.Value = true;
            app.inputImageButton = uibutton(app.UIFigure, 'push');
            app.inputImageButton.ButtonPushedFcn = createCallbackFcn(app, @inputImage, true);
            app.inputImageButton.Text = 'inputImage';
            app.imageinpaintingdemoLabel = uilabel(app.UIFigure);
            app.imageinpaintingdemoLabel.Text = 'image inpainting demo';
            app.patchsizeEditFieldLabel = uilabel(app.UIFigure);
            app.patchsizeEditFieldLabel.Text = 'patch size:';
            app.patchsizeEditField = uieditfield(app.UIFigure, 'text');
            app.patchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @patch, true);
            app.patchsizeEditField.Value = '3';
            app.dateLabel = uilabel(app.UIFigure);
            app.dateLabel.Text = 'date:';
            app.dateDatePicker = uidatepicker(app.UIFigure);
            app.dateDatePicker.Value = datetime([2020 8 7]);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Note: Please input your image under the ''images'' folder to inpaint the image! After the parameters are set and run in the background, the processing results will be displayed after a while!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageInpainting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end